classdef Battery_Parameter_Estimator_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ECMTypeDropDown              matlab.ui.control.DropDown
        ECMTypeDropDownLabel         matlab.ui.control.Label
        DatasefFileEditField         matlab.ui.control.EditField
        DatasefFileLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        SelectFileButton             matlab.ui.control.Button
        NumberofSplitSegmentSlider   matlab.ui.control.Slider
        NumberofSplitSegmentSliderLabel  matlab.ui.control.Label
        NominalCapacitySpinner       matlab.ui.control.Spinner
        NominalCapacitySpinnerLabel  matlab.ui.control.Label
        CurveFittingMrthodDropDown   matlab.ui.control.DropDown
        CurveFittingMrthodDropDownLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');  %open a mat file
            app.DatasefFileEditField.Value = path + "" + file;
            Dataset_File = load(file);
            assignin('base','Dataset_File',Dataset_File);
            segment_num = app.NumberofSplitSegmentSlider.Value;
            SOC_Plot_act = 0:(100/(segment_num-1)):100; %percent
            assignin('base','segment_num',segment_num);
            assignin('base','SOC_Plot_act',SOC_Plot_act);
            Architecture = app.ECMTypeDropDown.Value;
            assignin('base','Architecture',Architecture);
            fittype = app.CurveFittingMrthodDropDown.Value;
            assignin('base','method',fittype);
            Capacity = app.NominalCapacitySpinner.Value;
            assignin('base','Nominal_Capacity',Capacity);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurveFittingMrthodDropDownLabel = uilabel(app.UIFigure);
            app.CurveFittingMrthodDropDownLabel.Text = 'Curve Fitting Mrthod';
            app.CurveFittingMrthodDropDown = uidropdown(app.UIFigure);
            app.CurveFittingMrthodDropDown.Items = {'Polynomial 1th', 'Polynomial 3th', 'Polynomial 5th', 'Polynomial 8th'};
            app.CurveFittingMrthodDropDown.ItemsData = {'poly1', 'poly3', 'poly5', 'poly8'};
            app.CurveFittingMrthodDropDown.Value = 'poly5';
            app.NominalCapacitySpinnerLabel = uilabel(app.UIFigure);
            app.NominalCapacitySpinnerLabel.Text = 'Nominal Capacity';
            app.NominalCapacitySpinner = uispinner(app.UIFigure);
            app.NominalCapacitySpinner.Step = 0.1;
            app.NominalCapacitySpinner.Limits = [0 8];
            app.NominalCapacitySpinner.Value = 2.9;
            app.NumberofSplitSegmentSliderLabel = uilabel(app.UIFigure);
            app.NumberofSplitSegmentSliderLabel.Text = 'Number of Split Segment';
            app.NumberofSplitSegmentSlider = uislider(app.UIFigure);
            app.NumberofSplitSegmentSlider.Limits = [10 110];
            app.NumberofSplitSegmentSlider.MajorTicks = [11 22 33 44 55 66 77 88 99 110];
            app.NumberofSplitSegmentSlider.MinorTicks = [];
            app.NumberofSplitSegmentSlider.Value = 11;
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Placeholder = 'Ah';
            app.TextArea.Value = {'Ah'};
            app.DatasefFileLabel = uilabel(app.UIFigure);
            app.DatasefFileLabel.Text = 'Datasef File:';
            app.DatasefFileEditField = uieditfield(app.UIFigure, 'text');
            app.ECMTypeDropDownLabel = uilabel(app.UIFigure);
            app.ECMTypeDropDownLabel.Text = 'ECM Type';
            app.ECMTypeDropDown = uidropdown(app.UIFigure);
            app.ECMTypeDropDown.Items = {'Static', 'Dynamic'};
            app.ECMTypeDropDown.ItemsData = [0 1];
            app.ECMTypeDropDown.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Battery_Parameter_Estimator_Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end